classdef EYApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout6                   matlab.ui.container.GridLayout
        RunSimulationButton           matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        GridLayout11                  matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        GeneralInputforEnergyYieldPanel  matlab.ui.container.Panel
        LocationDropDownLabel         matlab.ui.control.Label
        LocationDropDown              matlab.ui.control.DropDown
        TrackingmethodDropDownLabel   matlab.ui.control.Label
        TrackingmethodDropDown        matlab.ui.control.DropDown
        RotationAngleLabel            matlab.ui.control.Label
        RotationAngleEditField        matlab.ui.control.NumericEditField
        AlbedoDropDownLabel           matlab.ui.control.Label
        AlbedoDropDown                matlab.ui.control.DropDown
        UseDatabaseLabel              matlab.ui.control.Label
        CheckBoxCitiesDB              matlab.ui.control.CheckBox
        TiltAngleEditFieldLabel       matlab.ui.control.Label
        TiltAngleEditField            matlab.ui.control.EditField
        Image2                        matlab.ui.control.Image
        USAMap                        matlab.ui.control.UIAxes
        OpticsTab                     matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        OpticsConfigurationPanel      matlab.ui.container.Panel
        PolarizationDropDownLabel     matlab.ui.control.Label
        PolarizationDropDown          matlab.ui.control.DropDown
        AOIresolutionLabel            matlab.ui.control.Label
        AOIresolutionEditField        matlab.ui.control.NumericEditField
        WavelengthLabel               matlab.ui.control.Label
        Wavelength_start              matlab.ui.control.NumericEditField
        Wavelength_step               matlab.ui.control.NumericEditField
        Wavelength_stop               matlab.ui.control.NumericEditField
        StandaloneCheckBox            matlab.ui.control.CheckBox
        LighttrappingEditFieldLabel   matlab.ui.control.Label
        LighttrappingEditField        matlab.ui.control.NumericEditField
        bifacialCheckBox              matlab.ui.control.CheckBox
        AbsorbersTextAreaLabel        matlab.ui.control.Label
        AbsorbersTextArea             matlab.ui.control.TextArea
        PlotAnglesLabel               matlab.ui.control.Label
        PlotAnglesEditField           matlab.ui.control.EditField
        ElectricsTab                  matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        ElectricsConfigurationPanel   matlab.ui.container.Panel
        RSHtandemEditFieldLabel       matlab.ui.control.Label
        RSHtandemEditField            matlab.ui.control.NumericEditField
        ElectricsConfigDropDownLabel  matlab.ui.control.Label
        ElectricsConfigDropDown       matlab.ui.control.DropDown
        RSHDropDownLabel              matlab.ui.control.Label
        RSHDropDown                   matlab.ui.control.DropDown
        RStandemEditFieldLabel        matlab.ui.control.Label
        RStandemEditField             matlab.ui.control.NumericEditField
        SimulationModelDropDownLabel  matlab.ui.control.Label
        SimulationModelDropDown       matlab.ui.control.DropDown
        RSHLabel                      matlab.ui.control.Label
        RSHEditField                  matlab.ui.control.EditField
        RSEditFieldLabel              matlab.ui.control.Label
        RSEditField                   matlab.ui.control.EditField
        J0EditFieldLabel              matlab.ui.control.Label
        J0EditField                   matlab.ui.control.EditField
        nEditFieldLabel               matlab.ui.control.Label
        nEditField                    matlab.ui.control.EditField
        tcJSCEditFieldLabel           matlab.ui.control.Label
        tcJSCEditField                matlab.ui.control.EditField
        tcVOCEditFieldLabel           matlab.ui.control.Label
        tcVOCEditField                matlab.ui.control.EditField
        NOCTEditField_2Label          matlab.ui.control.Label
        NOCTEditField                 matlab.ui.control.EditField
        CEEditFieldLabel              matlab.ui.control.Label
        CEEditField                   matlab.ui.control.EditField
        TemperatureEditFieldLabel     matlab.ui.control.Label
        TemperatureEditField          matlab.ui.control.EditField
        ElectricsConfigPic            matlab.ui.control.Image
        SettingsTab                   matlab.ui.container.Tab
        GridLayout10                  matlab.ui.container.GridLayout
        UsedatabasetospeedupsimulationsCheckBox  matlab.ui.control.CheckBox
        SetButtonEYresults            matlab.ui.control.StateButton
        SetButtonOpticsResults        matlab.ui.control.StateButton
        LoadButton_2                  matlab.ui.control.StateButton
        LoadButton                    matlab.ui.control.StateButton
        RefractiveindexdatabaseEditField  matlab.ui.control.EditField
        RefractiveindexdatabaseEditFieldLabel  matlab.ui.control.Label
        LTSpiceEditField              matlab.ui.control.EditField
        LTSpiceEditFieldLabel         matlab.ui.control.Label
        OpticsresultfolderEditField   matlab.ui.control.EditField
        OpticsresultfolderEditFieldLabel  matlab.ui.control.Label
        EnergyYieldresultfolderEditFieldLabel  matlab.ui.control.Label
        EnergyYieldresultfolderEditField  matlab.ui.control.EditField
        CreditsTab                    matlab.ui.container.Tab
        GridLayout16                  matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        HTML                          matlab.ui.control.HTML
        Panel_2                       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        EditField                     matlab.ui.control.EditField
        ListBox                       matlab.ui.control.ListBox
        UITable                       matlab.ui.control.Table
        GridLayout4                   matlab.ui.container.GridLayout
        AddmixedButton                matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        GridLayout5                   matlab.ui.container.GridLayout
        TabGroup2                     matlab.ui.container.TabGroup
        RefractiveindexTab            matlab.ui.container.Tab
        GridLayout14                  matlab.ui.container.GridLayout
        IndRefr_n                     matlab.ui.control.UIAxes
        IndRefr_k                     matlab.ui.control.UIAxes
        CompareresultsTab             matlab.ui.container.Tab
        GridLayout15                  matlab.ui.container.GridLayout
        results                       matlab.ui.control.ListBox
        cRaphaelSchmagerKITVersion10Label  matlab.ui.control.Label
        GridLayout_2                  matlab.ui.container.GridLayout
        SelectpropertyLabel2          matlab.ui.control.Label
        PlotSelect2                   matlab.ui.control.ListBox
        EnergyYieldresultsLabel       matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        GridLayout                    matlab.ui.container.GridLayout
        mAcmLabel                     matlab.ui.control.Label
        SelectpropertyLabel           matlab.ui.control.Label
        PlotSelect1                   matlab.ui.control.ListBox
        PlotSelect1_jsc               matlab.ui.control.ListBox
        OpticsresultsLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        Lamp                          matlab.ui.control.Lamp
        ClearDataButton               matlab.ui.control.Button
        ExportResultsButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        long
        lat
        CodeLocation
        AliasLocation
        CityTable
        states
        faceColors
        IndexDBName
        IndexDBPath
        IndRefr
        initiallist
        selectedLayer
        lambda
        lambdaTMM
        RowSelected
        tabletmp
        StoreInDatabase
        irradiance
        optics
        electrics
        EY
        PathOpticsResults
        PathEYResults
        AM15spectrum
        lstid
    end
    
    methods (Access = private)
        
        % ###################
        % ### Load config ###
        % ###################
        function LoadState(app)
            load('EYApp.lastsaved','-mat', 'props', 'values', 'visibilities','tabledata');
            
            for i = 1:length(props)
                try%#ok<TRYNC>
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        app.(propName).Value = values{i};
                    end
                    if isprop(property, 'Visible')
                        app.(props{i}).Visible = visibilities{i};
                    end
                end
            end
            app.UITable.Data = tabledata; clear tabledata props values visibilities;
        end
        
        % ###################
        % ### Save config ###
        % ###################
        function SaveState(app)
            props = properties(app);
            values = cell(1, length(props));
            visibilities = cell(1, length(props));
            
            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Visible')
                    visibilities{i} = app.(props{i}).Visible;
                end
            end
            tabledata = app.UITable.Data;
            
            save('EYApp.lastsaved','props', 'values', 'visibilities', 'tabledata');
        end
        
        % ########################
        % ### Plot the USA map ###
        % ########################
        function plotUSA(app)
            cla(app.USAMap)
            app.states = shaperead('usastatelo', 'UseGeoCoords', true, 'Selector',{@(name) ~any(strcmp(name,{'Alaska','Hawaii'})), 'Name'});
            app.faceColors = makesymbolspec('Polygon',{'INDEX', [1 numel(app.states)], 'FaceColor',polcmap(numel(app.states),.2)});
            
            ax = usamap('conus');
            geoshow(ax, app.states, 'DisplayType', 'polygon', 'SymbolSpec', app.faceColors);
            if app.long~=0
                hold on; geoshow(app.lat,app.long,'DisplayType','point','Marker','.','Markersize',30); hold off
            end
            framem off; gridm off; mlabel off; plabel off;
            copyobj(ax.Children, app.USAMap);
            delete(ax.Parent);
            axis(app.USAMap,'off')
            title(app.USAMap,'')
            %             % alternative:
            %             app.UIFigure.HandleVisibility = 'on';
            %             set(app.UIFigure,'CurrentAxes',app.USAMap)
            %             geobubble(app.lat,app.long,'Basemap','darkwater');
            %             app.UIFigure.HandleVisibility = 'on';
        end
        
        % ##################################
        % ### Load refractive index data ###
        % ##################################
        function LoadIndexData(app,replace)
            if isempty(app.RefractiveindexdatabaseEditField.Value) && replace==false
                bla=uiconfirm(app.UIFigure,'Please select a refractive index database!',' Refractive index database');
                if strcmp(bla,'OK')
                    [app.IndexDBName,app.IndexDBPath] = uigetfile({'*.xlsx';'*.xls'});
                    app.RefractiveindexdatabaseEditField.Value = [app.IndexDBPath,app.IndexDBName];
                end
            elseif isempty(app.RefractiveindexdatabaseEditField.Value) || replace
                [app.IndexDBName,app.IndexDBPath] = uigetfile({'*.xlsx';'*.xls'});
                app.RefractiveindexdatabaseEditField.Value = [app.IndexDBPath,app.IndexDBName];
            end
            [app.IndRefr.nkdata,app.IndRefr.names] = xlsread(app.RefractiveindexdatabaseEditField.Value);
            displayMaterials(app)
        end
        
        % #######################
        % ### Set Optics Path ###
        % #######################
        function SetSavePathOptics(app,replace)
            if isempty(app.OpticsresultfolderEditField.Value) && replace==false
                bla=uiconfirm(app.UIFigure,'Please select path to store optics data!','Path for Optics results');
                if strcmp(bla,'OK')
                    app.OpticsresultfolderEditField.Value = uigetdir();
                end
            elseif isempty(app.OpticsresultfolderEditField.Value) || replace
                app.OpticsresultfolderEditField.Value = uigetdir();
            end
            app.PathOpticsResults = [app.OpticsresultfolderEditField.Value,'\'];
        end
        
        % ###################
        % ### Set EY Path ###
        % ###################
        function SetSavePathEY(app,replace)
            if isempty(app.EnergyYieldresultfolderEditField.Value) && replace==false
                bla=uiconfirm(app.UIFigure,'Please select path to store EY data!','Path for EY results');
                if strcmp(bla,'OK')
                    app.EnergyYieldresultfolderEditField.Value = uigetdir();
                end
            elseif isempty(app.EnergyYieldresultfolderEditField.Value) || replace
                app.EnergyYieldresultfolderEditField.Value = uigetdir();
            end
            app.PathEYResults = [app.EnergyYieldresultfolderEditField.Value,'\'];
        end
        
        % ###################
        % ### Load cities ###
        % ###################
        function LoadCityList(app)
            app.CityTable = readtable('TMY3Locations.dat');
            app.LocationDropDown.Items = cellstr(table2array(app.CityTable(:,2)));
            app.LocationDropDown.ItemsData = (table2array(app.CityTable(:,1)));
            
            % filter already simulated ones
            if app.CheckBoxCitiesDB.Value
                tmpfolder=dir('Irradiance/*TYA_*');
                tmpfolder=regexp({tmpfolder.name}, 'Spectra_', 'split');
                tmpfolder=vertcat(tmpfolder{:});
                tmpfolder=tmpfolder(:,2);
                
                tmpfolder=regexp(tmpfolder, 'TYA_', 'split');
                tmpfolder=vertcat(tmpfolder{:});
                tmpfolder=tmpfolder(:,1);
                
                TF=contains(string(num2str(app.LocationDropDown.ItemsData')),tmpfolder);
                app.LocationDropDown.Items=app.LocationDropDown.Items(TF);
                app.LocationDropDown.ItemsData=app.LocationDropDown.ItemsData(TF);
            end
            
            app.CodeLocation = app.LocationDropDown.ItemsData(1);
            app.AliasLocation = string(app.LocationDropDown.Items(1));
        end
        
        % ##############################
        % ### Show materials in list ###
        % ##############################
        function displayMaterials(app)
            tmp = split(app.IndRefr.names(2:2:end)','_n');
            app.ListBox.Items = tmp(:,1);
            app.initiallist = app.ListBox.Items;
        end
        
        % #######################
        % ### Plot opticsdata ###
        % #######################
        function plotOpticsData(app,value,res)
            % Highlight the stuff what will be plotted in the listbox
            app.PlotSelect1.Value = value;
            app.PlotSelect1_jsc.Value = value;
            
            % detect which AOI(s) should be shown
            aoi=str2num(app.PlotAnglesEditField.Value)+1;
            
            cla(app.UIAxes)
            hold(app.UIAxes,"on")
            
            % only plot if optics simulation exists
            if isempty(app.optics{res})
                return;
            end
            
            for i=1:length(value)
                currvalue = app.PlotSelect1.Items(str2double(value{i}));
                if strcmp(currvalue,'R')
                    plot(app.UIAxes, app.lambdaTMM, squeeze(sum(app.optics{res}.Reflectance(aoi,:,:),2)),'--');
                elseif strcmp(currvalue,'T')
                    plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics{res}.Transmittance(aoi,:)) ,'--' );
                elseif contains(currvalue,'A_') && ~contains(currvalue,' back')
                    plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics{res}.Absorptance(str2double(value{i})-2,aoi,:)));
                elseif strcmp(currvalue,'R back')
                    plot(app.UIAxes, app.lambdaTMM, squeeze(sum(app.optics{res}.Reflectance_back(aoi,:,:),2)),'--');
                elseif strcmp(currvalue,'T back')
                    plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics{res}.Transmittance_back(aoi,:)) ,'--' );
                elseif contains(currvalue,'A_') && contains(currvalue,' back')
                    idx_a = numel(app.PlotSelect1.Items) - str2double(value{i}) + 1;
                    plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics{res}.Absorptance_back(idx_a,aoi,:)));
                end
            end
            xlim(app.UIAxes,[app.Wavelength_start.Value app.Wavelength_stop.Value])
            ylim(app.UIAxes,[0 1])
            hold(app.UIAxes,"off")
            
            legend(app.UIAxes,app.PlotSelect1.Items(str2double(value)),'Interpreter', 'none','EdgeColor','none');
        end
        
        % ####################
        % ### Plot EY data ###
        % ####################
        function plotEYdata(app,value,res)
            % Highlight the stuff what will be plotted in the listbox
            app.PlotSelect2.Value = value;
            
            resOptic = app.lstid(res,1);
            resEY = app.lstid(res,2);
            
            cla(app.UIAxes2)
            hold(app.UIAxes2,"on")
            
            % only plot if EY simulation exists
            if isempty(app.EY{resEY})
                return;
            end
            
            for i=1:length(value)
                currvalue = app.PlotSelect2.Items(str2double(value{i}));
                if size(app.EY{resEY}.(string(currvalue)),2) == 1
                    pllab = string(currvalue);
                elseif size(app.EY{resEY}.(string(currvalue)),2) > 1
                    pllab = strcat( repelem(string(currvalue),size(app.EY{resEY}.(string(currvalue)),2))', {' '},...
                        string({app.UITable.Data{app.optics{resOptic}.AbsorberIndex+1,1}}') )';
                end
                h=plot(app.UIAxes2, 1:8760, app.EY{resEY}.(string(currvalue)) );
                set(h, {'DisplayName'},arrayfun(@(x) x, pllab', 'un', 0)) ;
            end
            legend(app.UIAxes2, "show",'Interpreter', 'none','EdgeColor','none');
            
            % set y-label <--> not perfect, since different things might be
            % selected
            if contains(currvalue, 'Jsc') || contains(currvalue, 'JMPP')
                ylabel(app.UIAxes2,'Current density (mA/cm²)')
            elseif contains(currvalue, 'Voc') || contains(currvalue, 'VMPP')
                ylabel(app.UIAxes2,'Voltage (V)')
            elseif contains(currvalue, 'FF')
                ylabel(app.UIAxes2,'FF')
            elseif contains(currvalue, 'Power')
                ylabel(app.UIAxes2,'Power Output (W/m²)')
            elseif contains(currvalue, 'PCE')
                ylabel(app.UIAxes2,'"fake" PCE (%)')
            elseif contains(currvalue, 'Irradiance')
                ylabel(app.UIAxes2,'Solar intensity (W/m²)')
            elseif contains(currvalue, 'Temp')
                ylabel(app.UIAxes2,'Temperature (°C)')
            end
            
            
            % Fromat x-Axis in case of hours beeing displayed
            ttk = datetime(2019,1:12,15);           % desired tick locations, as datetimes
            tklbl = cellstr(datestr(ttk, 'mmm'));   % labels you want to match these ticks
            xtk = hours(ttk - datetime(2019,1,1));  % tick locations in hours
            set(app.UIAxes2, 'xtick', xtk, 'xticklabel', tklbl);
            xlim(app.UIAxes2,[0 8760])
            
            %             nametmp = strcat( repelem(app.PlotSelect2.Value, size(app.EY.(string(currvalue)),2) )', {' '}, ...
            %                 repmat( string({app.UITable.Data{app.optics.AbsorberIndex+1,1}}'), numel(value) ,1) );
            %
            %             legend(app.UIAxes2,nametmp,'Interpreter', 'none','EdgeColor','none');
            
            hold(app.UIAxes2,'off')
            
            %             app.PlotSelect1_jsc.Value = app.PlotSelect1.ItemsData( ismember(app.PlotSelect1.Items, app.PlotSelect1.Value) );
            %
            %             plot(app.UIAxes, app.lambdaTMM, squeeze(sum(app.optics.Reflectance(1,:,:),2)),'--'); hold(app.UIAxes,"on")
            %             plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics.Transmittance(1,:)) ,'--' );
            %             plot(app.UIAxes, app.lambdaTMM, squeeze(app.optics.Absorptance(app.optics.AbsorberIndex,1,:)));
            %             legend(app.UIAxes,app.PlotSelect1.Value,'Interpreter', 'none','EdgeColor','none'); hold(app.UIAxes,"off")
            %
            %             xlim(app.UIAxes,[app.Wavelength_start.Value app.Wavelength_stop.Value])
            %
            %             % If EY is calculated as well
            %             if ~app.StandaloneCheckBox.Value
            %                 app.PlotSelect2.Value = {'Power_Tandem','Power'};
            %
            %                 plot(app.UIAxes2,  1:8760, app.EY.Power_Tandem); hold(app.UIAxes2,"on")
            %                 plot(app.UIAxes2, 1:8760, app.EY.Power);  hold(app.UIAxes2,"off")
            %                 legend(app.UIAxes2,{'Power_Tandem', app.UITable.Data{app.optics.AbsorberIndex+1,1}},'Interpreter', 'none','EdgeColor','none');
            %                 xlim(app.UIAxes2,[0,8760])
            %             end
        end
        
        % #####################
        % ### Calculate Jsc ###
        % #####################
        function Jsc = calcJSC(app,X)
            hc=1.98644568E-25; q=1.602E-19;
            
            % interpolate AM1.5g spectrum to input wavelength spacing
            AM15 = interp1(app.AM15spectrum(:,1), app.AM15spectrum(:,2), app.lambdaTMM);
            
            % Calc Jsc
            Jsc = zeros(1,size(X,2));
            for i=1:size(X,2)
                Jsc(i) = round(trapz(app.lambdaTMM, q * squeeze(X(:,i))'.*(app.lambdaTMM*1E-9)/hc.*AM15 ) /10,1);
            end
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ######  REMOVE in APP ######
            % for testing: define folders
            addpath(genpath('./')); % all folders of EY Code
            % ######  REMOVE in APP ######
            
            try %#ok<TRYNC>
                LoadState(app);
            end
            
            % Load list of cities and coordinates
            LoadCityList(app);
            
            % Load default refractive index data
            LoadIndexData(app,false);
            
            % Set folders to save results
            if app.UsedatabasetospeedupsimulationsCheckBox.Value
                SetSavePathOptics(app,false);
                SetSavePathEY(app,false);
            end
            
            % Load AM1.5g data
            tmp = load('AM15gSpectrum.mat','AM15spectrum'); app.AM15spectrum = tmp.AM15spectrum; clear tmp;
            
            % Load albedo materials
            tmpfolder = dir('EnergyYield/LibraryEcospec/');
            app.AlbedoDropDown.Items = [{'none'},cellfun(@(x) extractBefore(x,'.txt'), {tmpfolder(3:end).name}, 'un', 0)];
            app.AlbedoDropDown.Value = {'none'};
            
            % Plot USA Map
            app.states = shaperead('usastatelo', 'UseGeoCoords', true, 'Selector',{@(name) ~any(strcmp(name,{'Alaska','Hawaii'})), 'Name'});
            app.faceColors = makesymbolspec('Polygon',{'INDEX', [1 numel(app.states)], 'FaceColor',polcmap(numel(app.states),.2)});
            app.lat = table2array(app.CityTable(table2array(app.CityTable(:,1))==app.LocationDropDown.Value,3));
            app.long = table2array(app.CityTable(table2array(app.CityTable(:,1))==app.LocationDropDown.Value,4));
            plotUSA(app);
            
            % Display Electrics config
            ElectricsConfigValueChanged(app);
            
            % Set Layertype
            app.UITable.ColumnFormat = {[] [] {'incoherent' 'coherent'} {'Flat','RegularUpright','RandomUpright','Inverted'}};
            
            %             % set layers / stack from last time
            %             try
            %                 saved = load('TMM.lastsaved','-mat');
            %                 app.UITable.Data = cell(length(saved.layers),2);
            %                 app.UITable.Data(:,1) = saved.layers;
            %                 app.UITable.Data(:,2) = saved.thicknesses;
            %             end
            %
            %             % load AM1.5
            %             app.astmg173=cell2mat(struct2cell(load('GlobalTilt_detail.mat')));
            %             %             app.astmg173=cell2mat(struct2cell(load('GlobalTilt_detail.mat')));
            %             app.hc=1.98644568E-25; app.q=1.602E-19;
            
            % Save state of the app
            SaveState(app)
            
            
        end

        % Value changed function: StandaloneCheckBox
        function StandaloneCheckBoxValueChanged(app, event)
            if app.StandaloneCheckBox.Value
                set(app.GeneralInputforEnergyYieldPanel,'Enable', 'off');
                set(app.ElectricsConfigurationPanel,'Enable', 'off');
            else
                set(app.GeneralInputforEnergyYieldPanel,'Enable', 'on');
                set(app.ElectricsConfigurationPanel,'Enable', 'on');
            end
            
        end

        % Callback function
        function BackSiliconDropDownValueChanged(app, event)
            if strcmp(app.BackSiliconDropDown.Value,'User defined value')
                set(app.LighttrappingEditField,'Enable', 'on');
            else
                set(app.LighttrappingEditField,'Enable', 'off');
            end
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            app.CodeLocation = app.LocationDropDown.Value;
            app.AliasLocation = string(app.LocationDropDown.Items(app.LocationDropDown.ItemsData'==app.LocationDropDown.Value));
            
            app.lat = table2array(app.CityTable(table2array(app.CityTable(:,1))==app.LocationDropDown.Value,3));
            app.long = table2array(app.CityTable(table2array(app.CityTable(:,1))==app.LocationDropDown.Value,4));
            
            plotUSA(app);
        end

        % Value changed function: SimulationModelDropDown
        function SimulationModelDropDownValueChanged(app, event)
            if strcmp(app.SimulationModelDropDown.Value,'numerical (LTSpice)')
                msgbox({'Attention!';'You selected LTSpice to simulate the electrical behavoir. [add explanation]'},'LTSpice Simulation selected');
            end
        end

        % Value changed function: LoadButton
        function LoadButtonValueChanged(app, event)
            LoadIndexData(app,true);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % Plot n and k data
            [~,ind] = ismember(app.ListBox.Value, app.initiallist);
            
            plot(app.IndRefr_n, app.IndRefr.nkdata(:,1), app.IndRefr.nkdata(:,2*ind));
            xlim(app.IndRefr_n,[app.Wavelength_start.Value app.Wavelength_stop.Value])
            legend(app.IndRefr_n,app.ListBox.Value,'Interpreter', 'none','EdgeColor','none');
            
            plot(app.IndRefr_k, app.IndRefr.nkdata(:,1), app.IndRefr.nkdata(:,2*ind+1));
            xlim(app.IndRefr_k,[app.Wavelength_start.Value app.Wavelength_stop.Value])
            legend(app.IndRefr_k,app.ListBox.Value,'Interpreter', 'none','EdgeColor','none');
            
            % Set selected layer to current value
            app.selectedLayer = char(app.ListBox.Value);
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            if isempty(event.Value)
                app.ListBox.Items = app.initiallist;
            else
                app.ListBox.Items = app.initiallist(startsWith(app.initiallist,event.Value,'IgnoreCase',true));
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.UITable.Data)
                app.UITable.Data{1,1} = app.selectedLayer;
                app.UITable.Data{1,2} = Inf;
                app.UITable.Data{1,3} = 'incoherent';
                app.UITable.Data{1,4} = '';
            else
                if ~isempty(app.RowSelected)
                    app.UITable.Data = app.tabletmp;
                    app.UITable.Data{app.RowSelected+1,1} = app.selectedLayer;
                    app.UITable.Data{app.RowSelected+1,2} = 0;
                    app.RowSelected = [];
                else
                    app.UITable.Data{end+1,1} = app.selectedLayer;
                    app.UITable.Data{end,2} = 0;
                end
            end
            
            % Save current state of app
            SaveState(app)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.RowSelected = event.Indices(:,1);
            
            tmp = cell(size(app.UITable.Data,1)+1,size(app.UITable.Data,2));
            tmp(1:app.RowSelected,:) = app.UITable.Data(1:app.RowSelected,:);
            tmp(app.RowSelected+2:end,:) = app.UITable.Data(app.RowSelected+1:end,:);
            app.tabletmp = tmp;
        end

        % Value changed function: CheckBoxCitiesDB
        function CheckBoxCitiesDBValueChanged(app, event)
            LoadCityList(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data = [];
            app.RowSelected = [];
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            if ~isempty(app.UITable.Data{1,4})
                app.UITable.Data{1,4} = '';
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if strcmp(app.UITable.Data{event.Indices(1),3}, "coherent")
                app.UITable.Data{event.Indices(1),4} = 'Flat';
            end
            
            % Detect ranges for thickness
            if event.Indices(2) == 2 && length(str2num(event.EditData)) > 1
                app.UITable.Data{event.Indices(1),2} = event.EditData;
                %app.UITable.ColumnFormat = repmat( {'numeric'}, 1, size(app.UITable.Data,2) );
            elseif event.Indices(2) == 2
                app.UITable.Data(event.Indices(1),2) = {str2num(event.EditData)};
            end
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            % turn lamp red:
            app.Lamp.Color = 'Red'; drawnow;
            
            % #########################
            % ### PREPARE VARIABLES ###
            % #########################
            app.lambda = app.Wavelength_start.Value:app.Wavelength_stop.Value;
            
            % Format Tilt anlge
            SolarCellTiltAngle = str2num(app.TiltAngleEditField.Value);
            if max(SolarCellTiltAngle) >=90 || min(SolarCellTiltAngle) < 0
                warndlg('Tilt angle is not properly defined. Try again.');
                return
            end
            
            % ### Update missing fields in table
            for i=1:size(app.UITable.Data,1)
                if isempty(app.UITable.Data{i,3})
                    app.UITable.Data{i,3} = 'coherent';
                end
                if isempty(app.UITable.Data{i,4}) && i>1
                    app.UITable.Data{i,4} = 'Flat';
                end
            end
            app.UITable.Data{1,2} = Inf;
            app.UITable.Data{end,2} = Inf;
            app.UITable.Data{1,3} = 'incoherent';
            app.UITable.Data{end,3} = 'incoherent';


            % ###########################
            % ### Sweep? or multiple? ###
            % ###########################
            
            % Manual multiple simulations
            if isprop(app,'optics') && ~isempty(app.optics)
                simu = numel(app.optics) +1;  % define start of new simulation
            else
                simu = 1; % otherwise start over
            end
            
            % Check if either multiple AOI or multiple thicknesses should
            % be simulated.
            
            % Thickness: Check if there is an array somewhere and we have
            % to do a sweep over multiple thicknesses
            thickn = cellfun(@(x) str2num(string(x)), app.UITable.Data(:,2), 'un', 0);
            sz = cellfun('size',thickn,2);
            [a,b] = max(sz);
            
            if a>1 && length(SolarCellTiltAngle) == 1 % if multiple layers and one AOIs
                LayerThickness = zeros(a,length(sz));
                LayerThickness(:,b) = thickn{sz>1};
                LayerThickness(:,1:b-1) = repmat( cell2mat(app.UITable.Data(1:b-1,2))', [a 1]);
                LayerThickness(:,b+1:end) = repmat( cell2mat(app.UITable.Data(b+1:end,2))', [a 1]);
                
                simu=simu+(1:size(LayerThickness,1))-1;
                
            elseif a>1 && length(SolarCellTiltAngle) > 1  % if multiple layers and multiple AOIs
                app.UITable.Data(b,2) = {thickn{sz>1}(1)}; % then only use first layer
                LayerThickness = cell2mat(app.UITable.Data(:,2))';
                
                simu=simu+(1:length(SolarCellTiltAngle))-1;
                
            elseif a==1 && length(SolarCellTiltAngle) > 1  % if multiple AOIs only
                LayerThickness = cell2mat(app.UITable.Data(:,2))';
                
                simu=simu+(1:length(SolarCellTiltAngle))-1;
                
            else    % if nothing should be sweeped: return the thickness vector
                LayerThickness = cell2mat(app.UITable.Data(:,2))';
                %                 app.results.Items{simu} = num2str(simu);
                %                 app.results.ItemsData(simu) = simu;
            end
            
            
            % #####################
            % ### OPTICS MODULE ###
            % #####################
            Stack = app.UITable.Data(:,1)';
            Polarization = app.PolarizationDropDown.Value;
            app.lambdaTMM = app.Wavelength_start.Value:app.Wavelength_step.Value:app.Wavelength_stop.Value;
            AngleResolution = app.AOIresolutionEditField.Value;
            Morphology_tmp = logical(cell2mat(cellfun(@(x) strcmp(x,'incoherent'), app.UITable.Data(:,3)', 'un',0)));
            Morphology = app.UITable.Data(Morphology_tmp,4)';
            Morphology = Morphology(1,2:end);
            IncoherentLayers = Stack(cell2mat(cellfun(@(x) strcmp(x,'incoherent'), app.UITable.Data(:,3)', 'un',0)));
            bifacial = app.bifacialCheckBox.Value;
            if isempty(app.AbsorbersTextArea.Value')
                Absorbers = app.AbsorbersTextArea.Value';
            else
                Absorbers = {};
            end
            
            
            % Call Optics calculations & sweep, if multiple thicknesses are
            % provided
            for i=1:size(LayerThickness,1)
                app.optics{simu(i)} = OpticsModule(app.IndRefr, Stack, LayerThickness(i,:), AngleResolution,...
                    Morphology, bifacial, Polarization, app.lambdaTMM, app.PathOpticsResults,...
                    app.UsedatabasetospeedupsimulationsCheckBox.Value, IncoherentLayers, Absorbers);
                
                
                % Write list of possible plot options in listbox1
                app.PlotSelect1.Items = [ 'R'; 'T'; cellfun(@(x) strjoin(['A_',{x}]), Stack(2:end-1), 'un', 0)' ];
                
                % Define tmp R,T,A variables to calculate the Jsc
                R = squeeze(sum(app.optics{simu(i)}.Reflectance(1,:,:),2));
                T = squeeze(app.optics{simu(i)}.Transmittance(1,:,:))';
                A = squeeze(app.optics{simu(i)}.Absorptance(:,1,:))';
                app.optics{simu(i)}.Jsc = calcJSC(app,[R,T,A]);
                app.PlotSelect1_jsc.Items = string(app.optics{simu(i)}.Jsc);
                
                % Save, if bifacial is selected
                if app.bifacialCheckBox.Value  % add backward simulation, in case of bifacial
                    app.PlotSelect1.Items = [app.PlotSelect1.Items, cellfun(@(x) strjoin([{x},'back']), app.PlotSelect1.Items, 'un', 0) ];
                    R = squeeze(sum(app.optics{simu(i)}.Reflectance_back(1,:,:),2));
                    T = squeeze(app.optics{simu(i)}.Transmittance_back(1,:,:))';
                    A = squeeze(app.optics{simu(i)}.Absorptance_back(:,1,:))';
                    app.optics{simu(i)}.Jsc = [app.optics{simu(i)}.Jsc, calcJSC(app,[R,T,A])];
                    app.PlotSelect1_jsc.Items = string(app.optics{simu(i)}.Jsc);
                end
                
                % write details to result list, only if there is no AOI
                % sweep
                if length(SolarCellTiltAngle)==1 && size(LayerThickness,1)>1
                    app.results.Items{simu(i)} = [num2str(simu(i)), ': ', cell2mat(Stack(b)), ' ', num2str(LayerThickness(i,b)), 'nm '];
                    app.results.ItemsData(simu(i)) = simu(i);
                    app.lstid(simu(i),:) = [simu(i), simu(i)]; % write list id vector
                else
                    app.results.Items{simu(i)} = [num2str(simu(i)), ': ', char(join(string(Stack')+' ('+strtrim(string(num2str(LayerThickness')))+')'))];
                    app.results.ItemsData(simu(i)) = simu(i);
                    app.lstid(simu(i),:) = [simu(i), simu(i)]; % write list id vector
                end
            end
            
            
            % Write absobers in field.
            app.AbsorbersTextArea.Value = Stack(app.optics{simu(i)}.AbsorberIndex+1);
            
            
            % define ItemsData with 1:length() in order to beeing able
            % selecting the elements by id - and not value
            app.PlotSelect1.ItemsData = arrayfun(@(x) num2str(x), 1:length(app.PlotSelect1.Items), 'un', 0);
            app.PlotSelect1_jsc.ItemsData = app.PlotSelect1.ItemsData;
            
            % Default plot: R,T and A top of all absorbers
            plotOpticsData(app, [{'1','2'},arrayfun(@(x) num2str(x), (app.optics{simu(i)}.AbsorberIndex'+2)', 'un', 0)], simu(i) );
            
            
            % #########################################
            % ### Run the Energy Yield calculations ###
            % #########################################
            if ~app.StandaloneCheckBox.Value % only run EY calculations, if standalone-optics is not selected
                
                % #########################
                % ### IRRADIANCE MODULE ###   % currently, it's only loading!!
                % #########################
                tmpfolder=dir('Irradiance/*TYA_*');
                tmpindex=cell2mat(cellfun(@(x) contains(x, num2str(app.CodeLocation)), {tmpfolder.name}, 'un', 0));
                FolderNameIrradiance = strcat( pwd, ['\Irradiance\',tmpfolder(tmpindex).name] );
                FileNameIrradiance = strrep(tmpfolder(tmpindex).name,"Spectra","TMY3");
                
                if ~exist('app.irradiance','var') || strcmp(app.CodeLocation,app.irradiance.CodeLocation)
                    app.irradiance = load([FolderNameIrradiance,'/Irr_spectra_clouds.mat']);
                    tmp = load(strjoin([FolderNameIrradiance,'\',FileNameIrradiance,'.mat'],''));
                    app.irradiance.Data_TMY3 = tmp.Data_TMY3; clear tmp;
                    app.irradiance.CodeLocation = app.CodeLocation;
                end
                
                
                % ########################
                % ### DEFINE ELECTRICS ###
                % ########################
                app.electrics.configuration = app.ElectricsConfigDropDown.Value;
                app.electrics.shunt = app.RSHDropDown.Value;
                app.electrics.RshTandem = app.RSHtandemEditField.Value;
                app.electrics.RsTandem = app.RStandemEditField.Value;
                app.electrics.Rsh = str2num(app.RSHEditField.Value);
                app.electrics.Rs = str2num(app.RSEditField.Value);
                app.electrics.CE = str2num(app.CEEditField.Value);
                app.electrics.j0 = str2num(app.J0EditField.Value);
                app.electrics.n = str2num(app.nEditField.Value);
                app.electrics.Temp = str2num(app.TemperatureEditField.Value); % check GUI! add selector for TEMP or NOCT
                app.electrics.tcJsc = str2num(app.tcJSCEditField.Value);
                app.electrics.tcVoc = str2num(app.tcVOCEditField.Value);
                app.electrics.NOCT = str2num(app.NOCTEditField.Value);
                % add analytical or LTspice
                
                
                % ##############################
                % ### CALCULATE ENERGY YIELD ###
                % ##############################
                SolarCellRotationAngle = app.RotationAngleEditField.Value;
                tracking = str2double(app.TrackingmethodDropDown.Value);
                if strcmp(app.AlbedoDropDown.Value,'none')
                    albedo = 0;
                    groundtype = 'artificialblack';
                else
                    albedo = 1;
                    groundtype = app.AlbedoDropDown.Value;
                end
                
                % Call the EY calculations - either loop over different
                % AOI or different layer thicknesses. In (i*j) either i
                % or j is always 1.
                for j=1:length(SolarCellTiltAngle)
                    for i=1:size(LayerThickness,1)
                        
                        app.EY{simu(i*j)} = EnergyYield(app.irradiance, app.optics{simu(i)}, app.electrics, ...
                            SolarCellRotationAngle, SolarCellTiltAngle(j), tracking, albedo, groundtype,...
                            app.PathEYResults, app.UsedatabasetospeedupsimulationsCheckBox.Value);
                    end
                    
                    if length(SolarCellTiltAngle) > 1 % Write details to result list
                        app.results.Items{simu(j)} = [num2str(simu(i)), ': ','Tilt Angle: ', num2str(SolarCellTiltAngle(j)), '° '];
                        app.results.ItemsData(simu(j)) = simu(j);
                        
                        app.lstid(simu(j),:) = [simu(1), simu(j)]; % write list id vector
                    end
                end
                
                % Write list of possible plot options in listbox1
                tmpnames = fieldnames(app.EY{simu(i)});
                app.PlotSelect2.Items = tmpnames(1:end-4);
                
                % define ItemsData with 1:length() in order to beeing able
                % selecting the elements by id - and not value
                app.PlotSelect2.ItemsData = arrayfun(@(x) num2str(x), 1:length(app.PlotSelect2.Items), 'un', 0);
                
                % Default plot: Power of single cells and Tandem Power
                def = sum(cell2mat(cellfun(@(x) strcmp(x, app.PlotSelect2.Items), {'Power_Tandem';'Power'}, 'un', 0)),1);
                plotEYdata(app, app.PlotSelect2.ItemsData(logical(def)), simu(i))
                
            end
            
            
            % Save current state
            SaveState(app);
            
            % turn lamp green again:
            app.Lamp.Color = 'Green';
            
        end

        % Value changed function: PlotSelect1
        function PlotSelect1ValueChanged(app, event)
            % not nothing selected in result list
            if isempty(app.results.Value)
                app.results.Value = length(app.results.Items); % take last
            end
            % plot selcted value - take care, we need to select the
            % corresponding optics data out of lstid
            plotOpticsData(app, event.Value, app.lstid(app.results.Value,1))
        end

        % Value changed function: PlotSelect2
        function PlotSelect2ValueChanged(app, event)
            if isempty(app.results.Value) % if no result is selected, use last of list
                plotEYdata(app, event.Value, numel(app.results.Items))
            else
                resultsValueChanged(app, app.results)
            end
        end

        % Value changed function: results
        function resultsValueChanged(app, event)
            res = app.results.Value;
            valueOptics = app.PlotSelect1.Value;
            valueEY = app.PlotSelect2.Value;
            
            if length(res)==1
                % Plot optics and EY results for specific simulation
                plotOpticsData(app, valueOptics, app.lstid(res,1))
                plotEYdata(app, valueEY, res)
            else
                % Multiple simulations are selected
                % First, select only one property from list
                valueEY = valueEY(1);
                app.PlotSelect2.Value = valueEY;  % overwrite in listbox as well
                pos = str2num(cell2mat(valueEY));
                nam = cell2mat(app.PlotSelect2.Items(pos));
                
                if contains(nam,'Power')
                    for i=1:length(res)
                        prop(i,:) = sum(app.EY{res(i)}.(nam),1);
                    end
                    
                    bar(app.UIAxes2, prop ,'stacked');
                    legend(app.UIAxes2,'hide')
                    
                    xticks(app.UIAxes2,1:length(event.Value))
                    xlim(app.UIAxes2,'auto');
                    ylim(app.UIAxes2,'auto');
                    
                    xt = get(app.UIAxes2, 'XTick');
                    barpos = cumsum([zeros(size(prop,1),1) prop(:,1:end-1)],2) + prop/2;
                    
                    for k1 = 1:size(prop,2)
                        label=cellfun(@(x) strcat(x,' kWh/m²'), arrayfun(@(x) num2str(x,'%.0f'), prop(:,k1)/1000,'un',0), 'un', 0);
                        text(app.UIAxes2, xt, barpos(:,k1), label, 'HorizontalAlignment','center')
                    end

                    if contains(app.results.Items(event.Value(1)),'Tilt Angle:')
                        set(app.UIAxes2,'XTickLabel',cellfun(@(x) extractAfter(x,'Tilt Angle: '), app.results.Items(event.Value), 'un',0));
                    elseif contains(app.results.Items(event.Value),'nm')
                        set(app.UIAxes2,'XTickLabel',cellfun(@(x) extractAfter(extractBefore(x,'nm'),' '), app.results.Items(event.Value), 'un',0));
                    else
                        xticklabels(app.UIAxes2,event.Value);
                    end
                else
                    for i=1:length(res)
                        plot(app.UIAxes2,1:8760,app.EY{res(i)}.(nam)); hold(app.UIAxes2,"on")
                    end
                    hold(app.UIAxes2,"off")
                end
                
            end
            
        end

        % Value changed function: ElectricsConfigDropDown
        function ElectricsConfigValueChanged(app, event)
            % Check which electric config is selected and swap the icon
            if strcmp(app.ElectricsConfigDropDown.Value,'2T')
                switch length(str2num(app.RSEditField.Value))
                    case 1
                        app.ElectricsConfigPic.ImageSource = '2T_1cell.svg';
                    case 2
                        app.ElectricsConfigPic.ImageSource = '2T_2cells.svg';
                    case 3
                        app.ElectricsConfigPic.ImageSource = '2T_3cells.svg';
                    otherwise
                        app.ElectricsConfigPic.ImageSource = '2T_Ncells.svg';
                end
            elseif strcmp(app.ElectricsConfigDropDown.Value,'3T')
                app.ElectricsConfigPic.ImageSource = '3T.svg';
            elseif strcmp(app.ElectricsConfigDropDown.Value,'4T')
                app.ElectricsConfigPic.ImageSource = '4T.svg';
            end
            
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Clear optics and EY data
            app.optics ={};
            app.EY = {};
            app.results.Items = {};
            app.results.ItemsData = [];
            cla(app.UIAxes)
            cla(app.UIAxes2)
            app.PlotSelect1.Items = {};
            app.PlotSelect1.ItemsData = {};
            app.PlotSelect1_jsc.Items = {};
            app.PlotSelect1_jsc.ItemsData = {};
            app.PlotSelect2.Items = {};
            app.PlotSelect2.ItemsData = {};
        end

        % Value changed function: PlotAnglesEditField
        function PlotAnglesEditFieldValueChanged(app, event)
            plotOpticsData(app, app.PlotSelect1.Value, app.lstid(app.results.Value,1));
        end

        % Value changed function: SetButtonOpticsResults
        function SetButtonOpticsResultsValueChanged(app, event)
            SetSavePathOptics(app,true);
        end

        % Value changed function: SetButtonEYresults
        function SetButtonEYresultsValueChanged(app, event)
            SetSavePathEY(app,true)
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            [savefile,savepath] = uiputfile('*');
            
            optics = app.optics; EY = app.EY;            %#ok<ADPROPLC> 
            save([savepath,savefile], "optics", "EY");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.RunSimulationButton = uibutton(app.GridLayout6, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Panel = uipanel(app.GridLayout6);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout11 = uigridlayout(app.GeneralTab);
            app.GridLayout7 = uigridlayout(app.GridLayout11);
            app.GeneralInputforEnergyYieldPanel = uipanel(app.GridLayout7);
            app.GeneralInputforEnergyYieldPanel.Title = 'General Input for Energy Yield';
            app.LocationDropDownLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.GeneralInputforEnergyYieldPanel);
            app.LocationDropDown.Editable = 'on';
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.TrackingmethodDropDownLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.TrackingmethodDropDownLabel.Text = 'Tracking method';
            app.TrackingmethodDropDown = uidropdown(app.GeneralInputforEnergyYieldPanel);
            app.TrackingmethodDropDown.Items = {'None', '1-axis non-tilted east-west', '2-axis', '1-axis latitude tiled zenith rotation', 'latitude-tiled seesaw rotation'};
            app.TrackingmethodDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.TrackingmethodDropDown.Value = '0';
            app.RotationAngleLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.RotationAngleLabel.Text = 'Rotation Angle (°)';
            app.RotationAngleEditField = uieditfield(app.GeneralInputforEnergyYieldPanel, 'numeric');
            app.RotationAngleEditField.Limits = [0 360];
            app.RotationAngleEditField.Tooltip = {'0=north, 90=east, 180=south, 270=west'};
            app.RotationAngleEditField.Value = 180;
            app.AlbedoDropDownLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.AlbedoDropDownLabel.Text = 'Albedo';
            app.AlbedoDropDown = uidropdown(app.GeneralInputforEnergyYieldPanel);
            app.UseDatabaseLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.UseDatabaseLabel.Text = 'Use Database';
            app.CheckBoxCitiesDB = uicheckbox(app.GeneralInputforEnergyYieldPanel);
            app.CheckBoxCitiesDB.ValueChangedFcn = createCallbackFcn(app, @CheckBoxCitiesDBValueChanged, true);
            app.CheckBoxCitiesDB.Text = '';
            app.CheckBoxCitiesDB.Value = true;
            app.TiltAngleEditFieldLabel = uilabel(app.GeneralInputforEnergyYieldPanel);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle (°)';
            app.TiltAngleEditField = uieditfield(app.GeneralInputforEnergyYieldPanel, 'text');
            app.TiltAngleEditField.Value = '28';
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageSource = 'Logo.svg';
            app.USAMap = uiaxes(app.GridLayout7);
            title(app.USAMap, 'Title')
            xlabel(app.USAMap, 'X')
            ylabel(app.USAMap, 'Y')
            zlabel(app.USAMap, 'Z')
            app.USAMap.Visible = 'off';
            app.OpticsTab = uitab(app.TabGroup);
            app.OpticsTab.Title = 'Optics';
            app.GridLayout8 = uigridlayout(app.OpticsTab);
            app.OpticsConfigurationPanel = uipanel(app.GridLayout8);
            app.OpticsConfigurationPanel.Title = 'Optics Configuration';
            app.PolarizationDropDownLabel = uilabel(app.OpticsConfigurationPanel);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.PolarizationDropDown = uidropdown(app.OpticsConfigurationPanel);
            app.PolarizationDropDown.Items = {'TE', 'TM', 'mixed'};
            app.PolarizationDropDown.Value = 'mixed';
            app.AOIresolutionLabel = uilabel(app.OpticsConfigurationPanel);
            app.AOIresolutionLabel.Text = 'AOI resolution (°)';
            app.AOIresolutionEditField = uieditfield(app.OpticsConfigurationPanel, 'numeric');
            app.AOIresolutionEditField.Limits = [1 30];
            app.AOIresolutionEditField.Value = 5;
            app.WavelengthLabel = uilabel(app.OpticsConfigurationPanel);
            app.WavelengthLabel.Text = 'Wavelength (nm)';
            app.Wavelength_start = uieditfield(app.OpticsConfigurationPanel, 'numeric');
            app.Wavelength_start.Limits = [300 1199];
            app.Wavelength_start.RoundFractionalValues = 'on';
            app.Wavelength_start.Value = 300;
            app.Wavelength_step = uieditfield(app.OpticsConfigurationPanel, 'numeric');
            app.Wavelength_step.Limits = [1 100];
            app.Wavelength_step.Value = 5;
            app.Wavelength_stop = uieditfield(app.OpticsConfigurationPanel, 'numeric');
            app.Wavelength_stop.Limits = [301 2000];
            app.Wavelength_stop.RoundFractionalValues = 'on';
            app.Wavelength_stop.Value = 1200;
            app.StandaloneCheckBox = uicheckbox(app.OpticsConfigurationPanel);
            app.StandaloneCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandaloneCheckBoxValueChanged, true);
            app.StandaloneCheckBox.Text = 'Standalone';
            app.LighttrappingEditFieldLabel = uilabel(app.OpticsConfigurationPanel);
            app.LighttrappingEditFieldLabel.Enable = 'off';
            app.LighttrappingEditFieldLabel.Text = 'Light trapping';
            app.LighttrappingEditField = uieditfield(app.OpticsConfigurationPanel, 'numeric');
            app.LighttrappingEditField.Limits = [0 100];
            app.LighttrappingEditField.Enable = 'off';
            app.bifacialCheckBox = uicheckbox(app.OpticsConfigurationPanel);
            app.bifacialCheckBox.Text = 'bifacial';
            app.AbsorbersTextAreaLabel = uilabel(app.OpticsConfigurationPanel);
            app.AbsorbersTextAreaLabel.Text = 'Absorbers';
            app.AbsorbersTextArea = uitextarea(app.OpticsConfigurationPanel);
            app.PlotAnglesLabel = uilabel(app.OpticsConfigurationPanel);
            app.PlotAnglesLabel.Text = 'Plot Angle(s) (°):';
            app.PlotAnglesEditField = uieditfield(app.OpticsConfigurationPanel, 'text');
            app.PlotAnglesEditField.ValueChangedFcn = createCallbackFcn(app, @PlotAnglesEditFieldValueChanged, true);
            app.PlotAnglesEditField.Value = '0';
            app.ElectricsTab = uitab(app.TabGroup);
            app.ElectricsTab.Title = 'Electrics';
            app.GridLayout9 = uigridlayout(app.ElectricsTab);
            app.ElectricsConfigurationPanel = uipanel(app.GridLayout9);
            app.ElectricsConfigurationPanel.Title = 'Electrics Configuration';
            app.RSHtandemEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.RSHtandemEditFieldLabel.Text = 'RSH tandem (Ω)';
            app.RSHtandemEditField = uieditfield(app.ElectricsConfigurationPanel, 'numeric');
            app.RSHtandemEditField.Limits = [0 Inf];
            app.RSHtandemEditField.Value = 850;
            app.ElectricsConfigDropDownLabel = uilabel(app.ElectricsConfigurationPanel);
            app.ElectricsConfigDropDownLabel.Text = 'Electrics Config';
            app.ElectricsConfigDropDown = uidropdown(app.ElectricsConfigurationPanel);
            app.ElectricsConfigDropDown.Items = {'2T', '3T', '4T'};
            app.ElectricsConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectricsConfigValueChanged, true);
            app.ElectricsConfigDropDown.Value = '2T';
            app.RSHDropDownLabel = uilabel(app.ElectricsConfigurationPanel);
            app.RSHDropDownLabel.Text = 'RSH';
            app.RSHDropDown = uidropdown(app.ElectricsConfigurationPanel);
            app.RSHDropDown.Items = {'without', 'with'};
            app.RSHDropDown.Value = 'without';
            app.RStandemEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.RStandemEditFieldLabel.Text = 'RS tandem (Ω)';
            app.RStandemEditField = uieditfield(app.ElectricsConfigurationPanel, 'numeric');
            app.RStandemEditField.Limits = [0 Inf];
            app.RStandemEditField.Value = 15.9;
            app.SimulationModelDropDownLabel = uilabel(app.ElectricsConfigurationPanel);
            app.SimulationModelDropDownLabel.Text = 'Simulation Model';
            app.SimulationModelDropDown = uidropdown(app.ElectricsConfigurationPanel);
            app.SimulationModelDropDown.Items = {'analytical', 'numerical (LTSpice)'};
            app.SimulationModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationModelDropDownValueChanged, true);
            app.SimulationModelDropDown.Value = 'analytical';
            app.RSHLabel = uilabel(app.ElectricsConfigurationPanel);
            app.RSHLabel.Text = 'RSH (Ω)';
            app.RSHEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.RSHEditField.Tooltip = {'Shunt resistance RSH in Ω of the single cells. Define the shunt resitance as array. E.g. for two cells: 1000, 1200.'};
            app.RSHEditField.Value = '1300, 800';
            app.RSEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.RSEditFieldLabel.Text = 'RS (Ω)';
            app.RSEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.RSEditField.Tooltip = {'Series resistance RS in Ω of the single cells. Define the series resitance as array. E.g. for two cells: 12, 15.'};
            app.RSEditField.Value = '12, 5';
            app.J0EditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.J0EditFieldLabel.Text = 'J0';
            app.J0EditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.J0EditField.Tooltip = {'J0 for the single cells. Define as array!'};
            app.J0EditField.Value = '3e-18, 3e-14';
            app.nEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.nEditField.Tooltip = {'n for the single cells. Define as array!'};
            app.nEditField.Value = '1.1, 1';
            app.tcJSCEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.tcJSCEditFieldLabel.Text = 'tc JSC';
            app.tcJSCEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.tcJSCEditField.Tooltip = {'TC Jsc for the single cells. Define as array!'};
            app.tcJSCEditField.Value = '0.0002, 0.00032';
            app.tcVOCEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.tcVOCEditFieldLabel.Text = 'tc VOC';
            app.tcVOCEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.tcVOCEditField.Tooltip = {'TC VOC for the single cells. Define as array!'};
            app.tcVOCEditField.Value = '-0.002, -0.0041';
            app.NOCTEditField_2Label = uilabel(app.ElectricsConfigurationPanel);
            app.NOCTEditField_2Label.Text = 'NOCT';
            app.NOCTEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.NOCTEditField.Tooltip = {'NOCT for the single cells. Define as array!'};
            app.NOCTEditField.Value = '48, 48';
            app.CEEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.CEEditFieldLabel.Text = 'CE';
            app.CEEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.CEEditField.Tooltip = {'CE for the single cells. Define as array!'};
            app.CEEditField.Value = '1,1';
            app.TemperatureEditFieldLabel = uilabel(app.ElectricsConfigurationPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.ElectricsConfigurationPanel, 'text');
            app.TemperatureEditField.Tooltip = {'CE for the single cells. Define as array!'};
            app.TemperatureEditField.Value = '25, 25';
            app.ElectricsConfigPic = uiimage(app.GridLayout9);
            app.ElectricsConfigPic.ImageSource = '2T_1cell.svg';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout10 = uigridlayout(app.SettingsTab);
            app.UsedatabasetospeedupsimulationsCheckBox = uicheckbox(app.GridLayout10);
            app.UsedatabasetospeedupsimulationsCheckBox.Text = 'Use database to speed up simulations';
            app.UsedatabasetospeedupsimulationsCheckBox.Value = true;
            app.SetButtonEYresults = uibutton(app.GridLayout10, 'state');
            app.SetButtonEYresults.ValueChangedFcn = createCallbackFcn(app, @SetButtonEYresultsValueChanged, true);
            app.SetButtonEYresults.Text = 'Set';
            app.SetButtonOpticsResults = uibutton(app.GridLayout10, 'state');
            app.SetButtonOpticsResults.ValueChangedFcn = createCallbackFcn(app, @SetButtonOpticsResultsValueChanged, true);
            app.SetButtonOpticsResults.Text = 'Set';
            app.LoadButton_2 = uibutton(app.GridLayout10, 'state');
            app.LoadButton_2.Text = 'Load';
            app.LoadButton = uibutton(app.GridLayout10, 'state');
            app.LoadButton.ValueChangedFcn = createCallbackFcn(app, @LoadButtonValueChanged, true);
            app.LoadButton.Text = 'Load';
            app.RefractiveindexdatabaseEditField = uieditfield(app.GridLayout10, 'text');
            app.RefractiveindexdatabaseEditFieldLabel = uilabel(app.GridLayout10);
            app.RefractiveindexdatabaseEditFieldLabel.Text = 'Refractive index database:';
            app.LTSpiceEditField = uieditfield(app.GridLayout10, 'text');
            app.LTSpiceEditFieldLabel = uilabel(app.GridLayout10);
            app.LTSpiceEditFieldLabel.Text = 'LTSpice:';
            app.OpticsresultfolderEditField = uieditfield(app.GridLayout10, 'text');
            app.OpticsresultfolderEditFieldLabel = uilabel(app.GridLayout10);
            app.OpticsresultfolderEditFieldLabel.Text = 'Optics result folder';
            app.EnergyYieldresultfolderEditFieldLabel = uilabel(app.GridLayout10);
            app.EnergyYieldresultfolderEditFieldLabel.Text = 'Energy Yield result folder';
            app.EnergyYieldresultfolderEditField = uieditfield(app.GridLayout10, 'text');
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.GridLayout16 = uigridlayout(app.CreditsTab);
            app.Label = uilabel(app.GridLayout16);
            app.Label.Text = '';
            app.HTML = uihtml(app.GridLayout16);
            app.HTML.HTMLSource = '<h1><strong><span style="font-size: 16px;"><span style="font-family: Arial,Helvetica,sans-serif;"><br />EYcalc - Energy Yield calculator</span></span></strong></h1><h3><strong><span style="font-size: 14px;"><span style="font-family: Arial,Helvetica,sans-serif;">Credits</span></span></strong></h3><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">This software project was initiated by <strong>Ulrich W. Paetzold</strong>. The code development was driven by:</span></span></p><ul><li><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">Raphael Schmager</span></span></li><li><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">Malte Langenhorst</span></span></li><li><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">Jonathan Lehr</span></span></li><li><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">Fabrizio Gota</span></span></li></ul><h3><strong><span style="font-size: 14px;"><span style="font-family: Arial,Helvetica,sans-serif;">Documentation</span></span></strong></h3><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">A detailed documentation and the source code can be found on our github repository:</span></span></p><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;"><a href="https://github.com/PerovskitePV/EYcalc">https://github.com/PerovskitePV/EYcalc</a></span></span></p><h3><strong><span style="font-size: 14px;"><span style="font-family: Arial,Helvetica,sans-serif;">License</span></span></strong></h3><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">This software is licensed under the <a href="https://www.gnu.org/licenses/gpl-3.0.html" rel="nofollow">GPLv3</a> license. </span></span></p><h3><strong><span style="font-size: 14px;"><span style="font-family: Arial,Helvetica,sans-serif;">Contact</span></span></strong></h3><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">For any questions regarding the software, please contact:</span></span></p><p><span style="font-size: 12px;"><span style="font-family: Arial,Helvetica,sans-serif;">Ulrich W. Paetzold<br /><a href="mailto:ulrich.paetzold@kit.edu">ulrich.paetzold@kit.edu</a></span></span></p>';
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Layers'; 'Thickness (nm)'; 'Layer type'; 'Morphology'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AddmixedButton = uibutton(app.GridLayout4, 'push');
            app.AddmixedButton.Text = 'Add mixed';
            app.RemoveButton = uibutton(app.GridLayout4, 'push');
            app.RemoveButton.Text = 'Remove';
            app.ResetButton = uibutton(app.GridLayout4, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.RefractiveindexTab = uitab(app.TabGroup2);
            app.RefractiveindexTab.Title = 'Refractive index';
            app.GridLayout14 = uigridlayout(app.RefractiveindexTab);
            app.IndRefr_n = uiaxes(app.GridLayout14);
            xlabel(app.IndRefr_n, 'Wavelength (nm)')
            ylabel(app.IndRefr_n, 'n')
            zlabel(app.IndRefr_n, 'Z')
            app.IndRefr_k = uiaxes(app.GridLayout14);
            xlabel(app.IndRefr_k, 'Wavelength (nm)')
            ylabel(app.IndRefr_k, 'k')
            zlabel(app.IndRefr_k, 'Z')
            app.CompareresultsTab = uitab(app.TabGroup2);
            app.CompareresultsTab.Title = 'Compare results';
            app.GridLayout15 = uigridlayout(app.CompareresultsTab);
            app.results = uilistbox(app.GridLayout15);
            app.results.Items = {};
            app.results.Multiselect = 'on';
            app.results.ValueChangedFcn = createCallbackFcn(app, @resultsValueChanged, true);
            app.results.Value = {};
            app.cRaphaelSchmagerKITVersion10Label = uilabel(app.GridLayout6);
            app.cRaphaelSchmagerKITVersion10Label.Tooltip = {''};
            app.cRaphaelSchmagerKITVersion10Label.Text = '(c) Raphael Schmager, KIT  -  Version 1.0';
            app.GridLayout_2 = uigridlayout(app.GridLayout6);
            app.SelectpropertyLabel2 = uilabel(app.GridLayout_2);
            app.SelectpropertyLabel2.Text = 'Select property';
            app.PlotSelect2 = uilistbox(app.GridLayout_2);
            app.PlotSelect2.Items = {};
            app.PlotSelect2.Multiselect = 'on';
            app.PlotSelect2.ValueChangedFcn = createCallbackFcn(app, @PlotSelect2ValueChanged, true);
            app.PlotSelect2.Value = {};
            app.EnergyYieldresultsLabel = uilabel(app.GridLayout_2);
            app.EnergyYieldresultsLabel.Text = 'Energy Yield results';
            app.UIAxes2 = uiaxes(app.GridLayout_2);
            xlabel(app.UIAxes2, 'Time (h)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout = uigridlayout(app.GridLayout6);
            app.mAcmLabel = uilabel(app.GridLayout);
            app.mAcmLabel.Text = 'mA/cm² ';
            app.SelectpropertyLabel = uilabel(app.GridLayout);
            app.SelectpropertyLabel.Text = 'Select property';
            app.PlotSelect1 = uilistbox(app.GridLayout);
            app.PlotSelect1.Items = {};
            app.PlotSelect1.Multiselect = 'on';
            app.PlotSelect1.ValueChangedFcn = createCallbackFcn(app, @PlotSelect1ValueChanged, true);
            app.PlotSelect1.Value = {};
            app.PlotSelect1_jsc = uilistbox(app.GridLayout);
            app.PlotSelect1_jsc.Items = {};
            app.PlotSelect1_jsc.Multiselect = 'on';
            app.PlotSelect1_jsc.Value = {};
            app.OpticsresultsLabel = uilabel(app.GridLayout);
            app.OpticsresultsLabel.Text = 'Optics results';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Reflectance, Transmittance, Absorptance')
            zlabel(app.UIAxes, 'Z')
            app.Lamp = uilamp(app.GridLayout6);
            app.ClearDataButton = uibutton(app.GridLayout6, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ExportResultsButton = uibutton(app.GridLayout6, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EYApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end